classdef NSPController < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TasksButtonGroup          matlab.ui.container.ButtonGroup
        CustomButton              matlab.ui.control.ToggleButton
        ConversationButton        matlab.ui.control.ToggleButton
        TestButton                matlab.ui.control.ToggleButton
        AMPsychButton             matlab.ui.control.ToggleButton
        UsePresetButton           matlab.ui.control.StateButton
        NamePresetsPanel          matlab.ui.container.Panel
        CustomTaskEditField       matlab.ui.control.EditField
        CustomTaskEditFieldLabel  matlab.ui.control.Label
        PatientIDEditField        matlab.ui.control.EditField
        PatientIDEditFieldLabel   matlab.ui.control.Label
        EMUEditField              matlab.ui.control.NumericEditField
        EMUEditFieldLabel         matlab.ui.control.Label
        CameraButton              matlab.ui.control.StateButton
        FilenameTextArea          matlab.ui.control.TextArea
        StopButton                matlab.ui.control.StateButton
        RecordButton              matlab.ui.control.StateButton
        FileDescriptionEditField  matlab.ui.control.EditField
        FilenameEditField         matlab.ui.control.EditField
    end


    properties (Access = private)
        NSPFilepath = 'C:\Users\User\Desktop\DATA'
        NSPFilename
        NSPFileDescription
        availableNSPs
        NSPAddress = {'192.168.137.1','192.168.137.177'};
        CameraOn = true;
        TaskPreset
    end

    methods (Access = private)

        function EstablishBlackrockConnection(app)
            app.availableNSPs = openBlackrockConnections(app.NSPAddress);
        end

        function recInProgressSetup(app,filename)
            if isempty(app.NSPFilename)
                app.FilenameEditField.Value = fullfile(app.NSPFilepath,filename);
            else
                app.FilenameEditField.Value = app.NSPFilename;
            end
            editTextArea(app)
            app.FileDescriptionEditField.Value = app.NSPFileDescription;
            app.RecordButton.Value = 1;
            app.StopButton.Enable = 'on';
        end

        function editTextArea(app)
            [~,filename,~] = fileparts(app.FilenameEditField.Value);
            if length(app.availableNSPs)>1
                tmp = cell(length(app.availableNSPs),1);
                for i = 1:length(tmp)
                    tmp{i} = ['...\',filename,'_NSP',int2str(i),'.nev or *.nsx'];
                end
                app.FilenameTextArea.Value = tmp;
            else
                app.FilenameTextArea.Value = {['...\',filename,'.nev or *.nsx']};
            end
        end

        function StartBlackrockRecording(app)
            [rec,filename] = cbmex('fileconfig');
            if ~rec
                startBlackrockRecordings(app.FilenameEditField.Value,app.availableNSPs,1,app.FileDescriptionEditField.Value);
                app.NSPFilename = app.FilenameEditField.Value;
                app.NSPFileDescription = app.FileDescriptionEditField.Value;
            else
                app.RecordButton.Value = 1;
                app.FilenameEditField.Value = fullfile(app.NSPFilepath,filename);
                editTextArea(app);
                warndlg('Recording currently in progress. Stop recording before continuing.','Recording In Progress')
            end
        end

        function StopBlackrockRecording(app)
            rec = cbmex('fileconfig');
            if rec
                stopBlackrockRecordings(app.FilenameEditField.Value,app.availableNSPs,1,0);
                app.NSPFilename = '';
                app.NSPFileDescription = '';
            end
        end

        function results = createPresetFilename(app)
            emuNum = sprintf('%03d',app.EMUEditField.Value);
            patientID = app.PatientIDEditField.Value;
            switch app.TasksButtonGroup.SelectedObject.Text
                case 'Custom'
                    taskID = app.CustomTaskEditField.Value;
                otherwise
                    taskID = app.TasksButtonGroup.SelectedObject.Text;
            end
            timeStr = char(datetime('now','Format','MM-dd-yy-HH-mm-ss-SSS'));
            results = ['EMU-',emuNum,'_subj-',patientID,'_task-',taskID,'_time-',timeStr];
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            EstablishBlackrockConnection(app)
            [rec,filename] = cbmex('fileconfig');
            if rec==1
                recInProgressSetup(app,filename)
            else
                app.FilenameEditField.Value = fullfile(app.NSPFilepath,'datafile');
                editTextArea(app)
            end
        end

        % Value changed function: RecordButton
        function RecordButtonValueChanged(app, event)
            if app.UsePresetButton.Value
                app.FilenameEditField.Value = fullfile(app.NSPFilepath,createPresetFilename(app));
                editTextArea(app);
            end
            StartBlackrockRecording(app)
            app.RecordButton.Value = 1;
            app.RecordButton.Enable = 'off';
            app.StopButton.Value = 0;
            app.StopButton.Enable = 'on';
        end

        % Value changed function: StopButton
        function StopButtonValueChanged(app, event)
            StopBlackrockRecording(app);
            app.RecordButton.Enable = 'on';
            app.RecordButton.Value = 0;
            app.StopButton.Value = 0;
            app.StopButton.Enable = 'off';
        end

        % Value changed function: FilenameEditField
        function FilenameEditFieldValueChanged(app, event)
            [path,filename] = fileparts(app.FilenameEditField.Value);
            app.FilenameEditField.Value = fullfile(path,filename);
            editTextArea(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ismac
                return
            end
            if ~isempty(app.availableNSPs)
                for i = length(app.availableNSPs):-1:1
                    cbmex('close','instance',app.availableNSPs(i)-1)
                end
            end
            delete(app)
        end

        % Value changed function: CameraButton
        function CameraButtonValueChanged(app, event)
            app.CameraOn = app.CameraButton.Value;

        end

        % Value changed function: UsePresetButton
        function UsePresetButtonValueChanged(app, event)
            app.NamePresetsPanel.Enable = app.UsePresetButton.Value;
            app.TasksButtonGroup.Enable = app.UsePresetButton.Value;
            app.FilenameEditField.Enable = ~app.UsePresetButton.Value;
            
        end

        % Selection changed function: TasksButtonGroup
        function TasksButtonGroupSelectionChanged(app, event)
            switch app.TasksButtonGroup.SelectedObject.Text
                case 'Custom'
                    app.CustomTaskEditField.Enable = 'on';
                    app.CustomTaskEditFieldLabel.Enable = 'on';
                otherwise
                    app.CustomTaskEditField.Enable = 'off';
                    app.CustomTaskEditFieldLabel.Enable = 'off';
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'NSP Controller';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FilenameEditField = uieditfield(app.UIFigure, 'text');
            app.FilenameEditField.ValueChangedFcn = createCallbackFcn(app, @FilenameEditFieldValueChanged, true);
            app.FileDescriptionEditField = uieditfield(app.UIFigure, 'text');
            app.RecordButton = uibutton(app.UIFigure, 'state');
            app.RecordButton.ValueChangedFcn = createCallbackFcn(app, @RecordButtonValueChanged, true);
            app.RecordButton.Icon = fullfile(pathToMLAPP, 'Icons', 'circle-24.png');
            app.RecordButton.Text = 'Record';
            app.StopButton = uibutton(app.UIFigure, 'state');
            app.StopButton.ValueChangedFcn = createCallbackFcn(app, @StopButtonValueChanged, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Icon = fullfile(pathToMLAPP, 'Icons', 'square-24.png');
            app.StopButton.Text = 'Stop';
            app.FilenameTextArea = uitextarea(app.UIFigure);
            app.FilenameTextArea.Editable = 'off';
            app.CameraButton = uibutton(app.UIFigure, 'state');
            app.CameraButton.ValueChangedFcn = createCallbackFcn(app, @CameraButtonValueChanged, true);
            app.CameraButton.Text = 'Camera';
            app.CameraButton.Value = true;
            app.NamePresetsPanel = uipanel(app.UIFigure);
            app.NamePresetsPanel.Enable = 'off';
            app.NamePresetsPanel.Title = 'Name Presets';
            app.EMUEditFieldLabel = uilabel(app.NamePresetsPanel);
            app.EMUEditFieldLabel.Text = 'EMU #';
            app.EMUEditField = uieditfield(app.NamePresetsPanel, 'numeric');
            app.EMUEditField.Limits = [1 Inf];
            app.EMUEditField.Value = 1;
            app.PatientIDEditFieldLabel = uilabel(app.NamePresetsPanel);
            app.PatientIDEditFieldLabel.Text = 'Patient ID';
            app.PatientIDEditField = uieditfield(app.NamePresetsPanel, 'text');
            app.CustomTaskEditFieldLabel = uilabel(app.NamePresetsPanel);
            app.CustomTaskEditFieldLabel.Enable = 'off';
            app.CustomTaskEditFieldLabel.Text = 'Custom Task';
            app.CustomTaskEditField = uieditfield(app.NamePresetsPanel, 'text');
            app.CustomTaskEditField.Enable = 'off';
            app.UsePresetButton = uibutton(app.UIFigure, 'state');
            app.UsePresetButton.ValueChangedFcn = createCallbackFcn(app, @UsePresetButtonValueChanged, true);
            app.UsePresetButton.Text = 'Use Preset';
            app.TasksButtonGroup = uibuttongroup(app.UIFigure);
            app.TasksButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TasksButtonGroupSelectionChanged, true);
            app.TasksButtonGroup.Enable = 'off';
            app.TasksButtonGroup.Title = 'Tasks';
            app.AMPsychButton = uitogglebutton(app.TasksButtonGroup);
            app.AMPsychButton.Text = 'AMPsych';
            app.AMPsychButton.Value = true;
            app.TestButton = uitogglebutton(app.TasksButtonGroup);
            app.TestButton.Text = 'Test';
            app.ConversationButton = uitogglebutton(app.TasksButtonGroup);
            app.ConversationButton.Text = 'Conversation';
            app.CustomButton = uitogglebutton(app.TasksButtonGroup);
            app.CustomButton.Text = 'Custom';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NSPController

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end