classdef SimpleEphysRecorder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        EMUEditField             matlab.ui.control.NumericEditField
        EMUEditFieldLabel        matlab.ui.control.Label
        ButtonGroup              matlab.ui.container.ButtonGroup
        TestButton               matlab.ui.control.RadioButton
        AMPsychButton            matlab.ui.control.RadioButton
        StopRecordingButton      matlab.ui.control.Button
        StartRecordingButton     matlab.ui.control.Button
        PatientIDEditField       matlab.ui.control.EditField
        PatientIDEditFieldLabel  matlab.ui.control.Label
        RecordingOnLamp          matlab.ui.control.Lamp
        RecordingOnLampLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        filename
        onlineNSPs
        neuralData
        videoData
    end
    
    methods (Access = private)
        
        function results = createFilename(app)
            emuNum = sprintf('%03d',app.EMUEditField.Value);
            patientID = app.PatientIDEditField.Value;
            taskID = app.ButtonGroup.SelectedObject.Text;
            timeStr = char(datetime('now','Format','MM-dd-yy-HH-mm-ss-SSS'));
            results = ['EMU-',emuNum,'_subj-',patientID,'_task-',taskID,'_time-',timeStr];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: PatientIDEditField
        function PatientIDEditFieldValueChanged(app, event)
            value = app.PatientIDEditField.Value;
            if ~isempty(value)
                app.StartRecordingButton.Enable = 1;
            else
                app.StartRecordingButton.Enable = 0;
            end
        end

        % Button pushed function: StartRecordingButton
        function StartRecordingButtonPushed(app, event)
            app.filename = createFilename(app);
%             app.filename = ['subj-',app.PatientIDEditField.Value,'_task-AMPsych_time-',datestr(now,'mm-dd-yy-HH-MM-SS-FFF')];
            [app.onlineNSPs,app.neuralData,app.videoData] = StartBlackrockAquisition(app.filename,1);
            app.RecordingOnLamp.Color = [1 0 0];
        end

        % Button pushed function: StopRecordingButton
        function StopRecordingButtonPushed(app, event)
            StopBlackrockAquisition(app.filename,app.onlineNSPs,app.videoData)
            app.RecordingOnLamp.Color = [0 0 0];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.RecordingOnLampLabel = uilabel(app.UIFigure);
            app.RecordingOnLampLabel.Text = 'Recording On';
            app.RecordingOnLamp = uilamp(app.UIFigure);
            app.PatientIDEditFieldLabel = uilabel(app.UIFigure);
            app.PatientIDEditFieldLabel.Text = 'Patient ID';
            app.PatientIDEditField = uieditfield(app.UIFigure, 'text');
            app.PatientIDEditField.ValueChangedFcn = createCallbackFcn(app, @PatientIDEditFieldValueChanged, true);
            app.StartRecordingButton = uibutton(app.UIFigure, 'push');
            app.StartRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StartRecordingButtonPushed, true);
            app.StartRecordingButton.Enable = 'off';
            app.StartRecordingButton.Text = 'Start Recording';
            app.StopRecordingButton = uibutton(app.UIFigure, 'push');
            app.StopRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StopRecordingButtonPushed, true);
            app.StopRecordingButton.Text = 'Stop Recording';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.AMPsychButton = uiradiobutton(app.ButtonGroup);
            app.AMPsychButton.Text = 'AMPsych';
            app.AMPsychButton.Value = true;
            app.TestButton = uiradiobutton(app.ButtonGroup);
            app.TestButton.Text = 'Test';
            app.EMUEditFieldLabel = uilabel(app.UIFigure);
            app.EMUEditFieldLabel.Text = 'EMU #';
            app.EMUEditField = uieditfield(app.UIFigure, 'numeric');
            app.EMUEditField.Limits = [1 Inf];
            app.EMUEditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SimpleEphysRecorder

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end