classdef SimpleNSPController < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        CameraButton              matlab.ui.control.StateButton
        FilenameTextArea          matlab.ui.control.TextArea
        NSPLamp                   matlab.ui.control.Lamp
        StopButton                matlab.ui.control.StateButton
        RecordButton              matlab.ui.control.StateButton
        FileDescriptionEditField  matlab.ui.control.EditField
        FilenameEditField         matlab.ui.control.EditField
    end


    properties (Access = private)
        NSPFilepath = 'C:\Users\User\Desktop\DATA'
        NSPFilename
        NSPFileDescription
        availableNSPs
        NSPAddress = {'192.168.137.1','192.168.137.177'};
        CameraOn = true;
    end

    methods (Access = private)

        function EstablishBlackrockConnection(app)
            app.availableNSPs = openBlackrockConnections(app.NSPAddress);
%             app.availableNSPs = zeros(size(app.NSPAddress));
%             tmp = app.availableNSPs;
%             for i=1:length(tmp)
%                 try
%                     cbmex('open','central-addr',app.NSPAddress{i},'instance',i-1)
%                 catch
%                     continue
%                 end
%                 tmp(i) = 1;
%             end
%             app.availableNSPs = find(tmp);
            if ~isempty(app.availableNSPs)
                app.NSPLamp.Color = [0 1 0];
            else
                app.NSPLamp.Color = [1 0 0];
            end
        end

        function recInProgressSetup(app,filename)
            if isempty(app.NSPFilename)
                app.FilenameEditField.Value = fullfile(app.NSPFilepath,filename);
            else
                app.FilenameEditField.Value = appNSPFilename;
            end
            editTextArea(app)
            app.FileDescriptionEditField.Value = app.NSPFileDescription;
            app.RecordButton.Value = 1;
            app.StopButton.Enable = 'on';
        end

        function editTextArea(app)
            [~,filename,~] = fileparts(app.FilenameEditField.Value);
            if length(app.availableNSPs)>1
                tmp = cell(length(app.availableNSPs),1);
                for i = 1:length(tmp)
                    tmp{i} = ['...\',filename,'_NSP',int2str(i),'.nev or *.nsx'];
                end
                app.FilenameTextArea.Value = tmp;
            else
                app.FilenameTextArea.Value = {['...\',filename,'.nev or *.nsx']};
            end
        end

        function StartBlackrockRecording(app)
            [rec,filename] = cbmex('fileconfig');
            if ~rec
                startBlackrockRecording(app.FilenameEditField.Value,app.availableNSPs,1,1,app.FileDescriptionEditField.Value)
%                 if length(app.availableNSPs)==1
%                     try
%                         cbmex('fileconfig',app.FilenameEditField.Value,app.FileDescriptionEditField.Value,1,'instance',app.availableNSPs-1)
%                         cbmex('comment', 255, 0, sprintf('%s','Recording Started'),'instance',app.availableNSPs-1);
%                         if app.CameraOn
%                             pause(1)
%                             cbmex('digitalout',2,'timed','frequency','input',30,'value',50,'instance',app.availableNSPs-1)
%                         end
%                     catch ME
%                         warndlg({'Error starting recording: ',ME.message},'Recording Start Error');
%                         return
%                     end
%                 elseif length(app.availableNSPs)>1
%                     try
%                         for i=1:length(app.availableNSPs)
%                             cbmex('fileconfig',[app.FilenameEditField.Value,'_NSP-',int2str(i)],app.FileDescriptionEditField.Value,1,'instance',app.availableNSPs(i)-1)
%                             cbmex('comment', 255, 0,['NSP',int2str(i),' Recording Started'],'instance',app.availableNSPs(i)-1);
%                         end
%                         if app.CameraOn
%                             pause(1)
%                             cbmex('digitalout',2,'timed','frequency','input',30,'value',50,'instance',app.availableNSPs(1)-1)
%                         end
%                     catch ME
%                         warndlg({'Error starting recording: ',ME.message},'Recording Start Error');
%                         return
%                     end
%                 end
                app.NSPFilename = app.FilenameEditField.Value;
                app.NSPFileDescription = app.FileDescriptionEditField.Value;
            else
                app.RecordButton.Value = 1;
                app.FilenameEditField.Value = fullfile(app.NSPFilepath,filename);
                editTextArea(app)
                warndlg('Recording currently in progress. Stop recording before continuing.','Recording In Progress')
            end
        end

        function StopBlackrockRecording(app)
            rec = cbmex('fileconfig');
            if rec
                stopBlackrockRecordings(app.FilenameEditField.Value,app.availableNSPs,1,0)
%                 if length(app.availableNSPs)==1
%                     try
%                         if app.CameraOn && app.availableNSPs(1)==1
%                             cbmex('digitalout',2,'disable','instance',app.availableNSPs(1)-1)
%                             pause(1)
%                         end
%                         cbmex('fileconfig',app.FilenameEditField.Value,app.FileDescriptionEditField.Value,0,'instance',app.availableNSPs-1,'option','none')
%                     catch ME
%                         warndlg({'Error stopping recording: ',ME.message},'Recording Stop Error')
%                         return
%                     end
%                 else
%                     try
%                         for i=1:length(app.availableNSPs)
%                             if app.CameraOn && app.availableNSPs(i)==1
%                                 cbmex('digitalout',2,'disable','instance',app.availableNSPs(i)-1)
%                                 pause(1)
%                             end
%                             cbmex('fileconfig',[app.FilenameEditField.Value,'_NSP-',int2str(i)],app.FileDescriptionEditField.Value,0,'instance',app.availableNSPs(i)-1,'option','none')
%                         end
%                     catch ME
%                         warndlg({'Error stopping recording: ',ME.message},'Recording Stop Error')
%                         return
%                     end
%                 end
                app.NSPFilename = '';
                app.NSPFileDescription = '';
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            EstablishBlackrockConnection(app)
            [rec,filename] = cbmex('fileconfig');
            if rec==1
                recInProgressSetup(app,filename)
            else
                app.FilenameEditField.Value = fullfile(app.NSPFilepath,'datafile');
                editTextArea(app)
            end
        end

        % Value changed function: RecordButton
        function RecordButtonValueChanged(app, event)
            StartBlackrockRecording(app)
            app.RecordButton.Value = 1;
            app.RecordButton.Enable = 'off';
            app.StopButton.Value = 0;
            app.StopButton.Enable = 'on';
        end

        % Value changed function: StopButton
        function StopButtonValueChanged(app, event)
            StopBlackrockRecording(app);
            app.RecordButton.Enable = 'on';
            app.RecordButton.Value = 0;
            app.StopButton.Value = 0;
            app.StopButton.Enable = 'off';
        end

        % Value changed function: FilenameEditField
        function FilenameEditFieldValueChanged(app, event)
            [path,filename] = fileparts(app.FilenameEditField.Value);
            app.FilenameEditField.Value = fullfile(path,filename);
            editTextArea(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ismac
                return
            end
            if ~isempty(app.availableNSPs)
                for i = length(app.availableNSPs):-1:1
                    cbmex('close','instance',app.availableNSPs(i)-1)
                end
            end
            delete(app)
        end

        % Value changed function: CameraButton
        function CameraButtonValueChanged(app, event)
            app.CameraOn = app.CameraButton.Value;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FilenameEditField = uieditfield(app.UIFigure, 'text');
            app.FilenameEditField.ValueChangedFcn = createCallbackFcn(app, @FilenameEditFieldValueChanged, true);
            app.FileDescriptionEditField = uieditfield(app.UIFigure, 'text');
            app.RecordButton = uibutton(app.UIFigure, 'state');
            app.RecordButton.ValueChangedFcn = createCallbackFcn(app, @RecordButtonValueChanged, true);
            app.RecordButton.Text = 'Record';
            app.StopButton = uibutton(app.UIFigure, 'state');
            app.StopButton.ValueChangedFcn = createCallbackFcn(app, @StopButtonValueChanged, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.NSPLamp = uilamp(app.UIFigure);
            app.FilenameTextArea = uitextarea(app.UIFigure);
            app.FilenameTextArea.Editable = 'off';
            app.CameraButton = uibutton(app.UIFigure, 'state');
            app.CameraButton.ValueChangedFcn = createCallbackFcn(app, @CameraButtonValueChanged, true);
            app.CameraButton.Text = 'Camera';
            app.CameraButton.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SimpleNSPController

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end